classdef Accumulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AccumulatorSetupUIFigure       matlab.ui.Figure
        AccumulatorPanel               matlab.ui.container.Panel
        Copyright2021BalticRacingbyEricDorniedenLabel  matlab.ui.control.Label
        CapacityCellEditField          matlab.ui.control.NumericEditField
        CapacityCellEditFieldLabel     matlab.ui.control.Label
        OverallCapacityLabel           matlab.ui.control.Label
        CellsinrowEditField            matlab.ui.control.NumericEditField
        CellsinrowEditFieldLabel       matlab.ui.control.Label
        CellsinparallelEditField       matlab.ui.control.NumericEditField
        CellsinparallelEditFieldLabel  matlab.ui.control.Label
        Energy_iEditField              matlab.ui.control.NumericEditField
        Energy_iEditFieldLabel         matlab.ui.control.Label
        V_iEditField                   matlab.ui.control.NumericEditField
        V_iEditFieldLabel              matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp   % Main app object
    end
    
    methods (Access = private)
        
        function saveData(app)
            app.CallingApp.V_i = app.V_iEditField.Value;
            app.CallingApp.Energy_i = app.Energy_iEditField.Value;
            app.CallingApp.nZellen_Parallel = app.CellsinparallelEditField.Value;
            app.CallingApp.nZellen_Reihe = app.CellsinrowEditField.Value;
            app.CallingApp.capacity_cell = app.CapacityCellEditField.Value;
        end
        
        function loadData(app)
            app.V_iEditField.Value = app.CallingApp.V_i;
            app.Energy_iEditField.Value = app.CallingApp.Energy_i;
            app.CellsinparallelEditField.Value = app.CallingApp.nZellen_Parallel;
            app.CellsinrowEditField.Value = app.CallingApp.nZellen_Reihe;
            app.CapacityCellEditField.Value = app.CallingApp.capacity_cell;
        end
        
        function updateCapacity(app)
            % Calculates Overall Capacity of the accumulator in Wh
            overall_capacity = app.CellsinrowEditField.Value * app.CellsinparallelEditField.Value * app.CapacityCellEditField.Value;
            app.OverallCapacityLabel.Text = "Overall Capacity " + overall_capacity + " [Wh]"; 
        end
    end
    
    methods (Access = public)
        
        function changeColorScheme(app) 
            PrimaryColor = app.CallingApp.PrimaryColor;
            OnPrimaryColor = app.CallingApp.OnPrimaryColor;   
            PrimaryVariantColor = app.CallingApp.PrimaryVariantColor;
            OnPrimaryVariantColor = app.CallingApp.OnPrimaryVariantColor;
            SecondaryColor = app.CallingApp.SecondaryColor;
            OnSecondaryColor = app.CallingApp.OnSecondaryColor;
            BackgroundColor = app.CallingApp.BackgroundColor;
            OnBackgroundColor = app.CallingApp.OnBackgroundColor;
            SurfaceColor = app.CallingApp.SurfaceColor;
            OnSurfaceColor = app.CallingApp.OnSurfaceColor;
            ErrorColor = app.CallingApp.ErrorColor;
            OnErrorColor = app.CallingApp.OnErrorColor;
            BorderType = app.CallingApp.BorderType;                 
            Host_Logo = app.CallingApp.Host_Logo;
            
            app.AccumulatorSetupUIFigure.Color = BackgroundColor;
            
            app.Copyright2021BalticRacingbyEricDorniedenLabel.FontColor = OnBackgroundColor;
            
            app.AccumulatorPanel.ForegroundColor = OnSurfaceColor;
            app.AccumulatorPanel.BackgroundColor = SurfaceColor;
            app.AccumulatorPanel.BorderType = BorderType;
            
            app.V_iEditFieldLabel.FontColor = OnPrimaryColor;
            app.V_iEditField.BackgroundColor = SurfaceColor;
            app.V_iEditField.FontColor = OnSurfaceColor;
            
            app.Energy_iEditFieldLabel.FontColor = OnPrimaryColor;
            app.Energy_iEditField.BackgroundColor = SurfaceColor;
            app.Energy_iEditField.FontColor = OnSurfaceColor;
            
            app.CellsinparallelEditFieldLabel.FontColor = OnPrimaryColor;
            app.CellsinparallelEditField.BackgroundColor = SurfaceColor;
            app.CellsinparallelEditField.FontColor = OnSurfaceColor;
            
            app.CellsinrowEditFieldLabel.FontColor = OnPrimaryColor;
            app.CellsinrowEditField.BackgroundColor = SurfaceColor;
            app.CellsinrowEditField.FontColor = OnSurfaceColor;
            
            app.CapacityCellEditFieldLabel.FontColor = OnPrimaryColor;
            app.CapacityCellEditField.BackgroundColor = SurfaceColor;
            app.CapacityCellEditField.FontColor = OnSurfaceColor;
            
            app.OverallCapacityLabel.FontColor = OnPrimaryColor;
            
            app.ResetButton.FontColor = OnPrimaryColor;
            app.ResetButton.BackgroundColor = PrimaryVariantColor;
            
            app.SaveButton.FontColor = OnPrimaryColor;
            app.SaveButton.BackgroundColor = PrimaryVariantColor;
                
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.CallingApp = mainapp;
            
            % Loads the Data from the mainapp
            loadData(app);       
            
            % updates the overall capacity of the accumulator
            updateCapacity(app);
        end

        % Close request function: AccumulatorSetupUIFigure
        function AccumulatorSetupUIFigureCloseRequest(app, event)
            saveData(app);
            
            % Disable Accumulator Options button while dialog is open
            app.CallingApp.AccumulatorButton.Enable = 'on';
            
            delete(app)      
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveData(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Loads the Data from the mainapp
            loadData(app);
        end

        % Value changed function: CellsinrowEditField
        function CellsinrowEditFieldValueChanged(app, event)
            updateCapacity(app);
        end

        % Value changed function: CapacityCellEditField
        function CapacityCellEditFieldValueChanged(app, event)
            updateCapacity(app);
        end

        % Value changed function: CellsinparallelEditField
        function CellsinparallelEditFieldValueChanged(app, event)
            updateCapacity(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AccumulatorSetupUIFigure = uifigure('Visible', 'off');
            app.AccumulatorSetupUIFigure.Name = 'Accumulator Setup';
            app.AccumulatorSetupUIFigure.Icon = 'Baltic.png';
            app.AccumulatorSetupUIFigure.CloseRequestFcn = createCallbackFcn(app, @AccumulatorSetupUIFigureCloseRequest, true);
            app.AccumulatorPanel = uipanel(app.AccumulatorSetupUIFigure);
            app.AccumulatorPanel.Title = 'Accumulator';
            app.SaveButton = uibutton(app.AccumulatorPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ResetButton = uibutton(app.AccumulatorPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.V_iEditFieldLabel = uilabel(app.AccumulatorPanel);
            app.V_iEditFieldLabel.Text = 'V_i';
            app.V_iEditField = uieditfield(app.AccumulatorPanel, 'numeric');
            app.Energy_iEditFieldLabel = uilabel(app.AccumulatorPanel);
            app.Energy_iEditFieldLabel.Text = 'Energy_i';
            app.Energy_iEditField = uieditfield(app.AccumulatorPanel, 'numeric');
            app.CellsinparallelEditFieldLabel = uilabel(app.AccumulatorPanel);
            app.CellsinparallelEditFieldLabel.Text = 'Cells in parallel';
            app.CellsinparallelEditField = uieditfield(app.AccumulatorPanel, 'numeric');
            app.CellsinparallelEditField.ValueChangedFcn = createCallbackFcn(app, @CellsinparallelEditFieldValueChanged, true);
            app.CellsinrowEditFieldLabel = uilabel(app.AccumulatorPanel);
            app.CellsinrowEditFieldLabel.Text = 'Cells in row';
            app.CellsinrowEditField = uieditfield(app.AccumulatorPanel, 'numeric');
            app.CellsinrowEditField.ValueChangedFcn = createCallbackFcn(app, @CellsinrowEditFieldValueChanged, true);
            app.OverallCapacityLabel = uilabel(app.AccumulatorPanel);
            app.OverallCapacityLabel.Text = 'Overall Capacity';
            app.CapacityCellEditFieldLabel = uilabel(app.AccumulatorPanel);
            app.CapacityCellEditFieldLabel.Text = 'Capacity Cell';
            app.CapacityCellEditField = uieditfield(app.AccumulatorPanel, 'numeric');
            app.CapacityCellEditField.ValueChangedFcn = createCallbackFcn(app, @CapacityCellEditFieldValueChanged, true);
            app.Copyright2021BalticRacingbyEricDorniedenLabel = uilabel(app.AccumulatorPanel);
            app.Copyright2021BalticRacingbyEricDorniedenLabel.Text = 'Copyright © 2021 Baltic Racing by Eric Dornieden';
            % Show the figure after all components are created
            app.AccumulatorSetupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Accumulator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AccumulatorSetupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AccumulatorSetupUIFigure)
        end
    end
end