classdef Aerodynamik < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AeroParametersPanel           matlab.ui.container.Panel
        cdEditFieldLabel              matlab.ui.control.Label
        cdEditField                   matlab.ui.control.NumericEditField
        AmEditFieldLabel              matlab.ui.control.Label
        AmEditField                   matlab.ui.control.NumericEditField
        UITable                       matlab.ui.control.Table
        Button                        matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        DownforcemultiplierEditFieldLabel  matlab.ui.control.Label
        DownforcemultiplierEditField  matlab.ui.control.NumericEditField
        SaveButton                    matlab.ui.control.Button
        clEditFieldLabel              matlab.ui.control.Label
        clEditField                   matlab.ui.control.NumericEditField
        ResetButton                   matlab.ui.control.Button
        AeroLoadFrontLabel            matlab.ui.control.Label
        AeroLoadFrontEditField        matlab.ui.control.NumericEditField
        PlotAeroDataButton            matlab.ui.control.Button
        LDLabel                       matlab.ui.control.Label
        SimulatewithDRSPanel          matlab.ui.container.Panel
        DRSefficiencyEditField        matlab.ui.control.NumericEditField
        DragCoeffc_wEditField         matlab.ui.control.NumericEditField
        DRSCheckBox                   matlab.ui.control.CheckBox
        DRSSwitch                     matlab.ui.control.Switch
    end

    
    properties (Access = private)
        CallingApp   % Main app object
    end
    
    methods (Access = private)
        
        function saveData(app)
            app.CallingApp.c_w = app.cdEditField.Value;
            app.CallingApp.c_l = app.clEditField.Value;
            app.CallingApp.A = app.AmEditField.Value;
%             app.CallingApp.downforce_data = app.UITable.Data;
            app.CallingApp.downforce_multiplier = app.DownforcemultiplierEditField.Value;      
            app.CallingApp.aero_pv = app.AeroLoadFrontEditField.Value;
            app.CallingApp.DRS = app.DRSCheckBox.Value;
            value = app.DRSSwitch.Value;
            if value == "cd"
               app.CallingApp.c_d_DRS = app.DragCoeffc_wEditField.Value;
            else
               app.CallingApp.c_d_DRS = app.cdEditField.Value-(app.cdEditField.Value*app.DRSefficiencyEditField.Value);
            end
            
        end
        
        function loadData(app)
             % Loads Values from Mainapp
            app.cdEditField.Value = app.CallingApp.c_w;
            app.clEditField.Value = app.CallingApp.c_l;
            app.AmEditField.Value = app.CallingApp.A;
%             app.UITable.Data = app.CallingApp.downforce_data;
            app.DownforcemultiplierEditField.Value = app.CallingApp.downforce_multiplier; 
            app.AeroLoadFrontEditField.Value = app.CallingApp.aero_pv;
            app.DRSCheckBox.Value = app.CallingApp.DRS;
            app.DragCoeffc_wEditField.Value = app.CallingApp.c_d_DRS;
            
        end
        
        
        function updateLD(app)
            app.LDLabel.Text = "L/D      " + num2str(app.clEditField.Value/app.cdEditField.Value);
        end
        
        function DRSUpdateCheckBox(app)
            value = app.DRSCheckBox.Value;
            if value
                app.DRSSwitch.Enable = true;
                DRSSwitchValueChanged(app);
            else
             app.DRSSwitch.Enable = false;
             app.DragCoeffc_wEditField.Enable = false;
             app.DRSefficiencyEditField.Enable = false;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.CallingApp = mainapp;     
            % DRS Enabler/Disabler
            DRSUpdateCheckBox(app);
            % Loads the data from the mainapp
            loadData(app);
            
            % Update Lift to Drag ratio
            updateLD(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Adds an entry to the UITable
            data = app.UITable.Data;
            app.UITable.Data = [data; [0 0]];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % Deletes the last entry in the UITable
            data = app.UITable.Data;
            data((length(data)),:) = [];
            app.UITable.Data = data;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Saves all entered data
            saveData(app);
            
            % Disable Aerodynamik Options button while dialog is open
            app.CallingApp.AerodynamikButton.Enable = 'on';
            
            delete(app)            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveData(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            loadData(app);
        end

        % Button pushed function: PlotAeroDataButton
        function PlotAeroDataButtonPushed(app, event)
            
            A = app.AmEditField.Value;
            c_w = app.cdEditField.Value;
            c_l = app.clEditField.Value;
            downforce_multiplier = app.DownforcemultiplierEditField.Value;
            aero_pv = app.AeroLoadFrontEditField.Value;
            
            plotAeroData(A,c_w,c_l,downforce_multiplier,aero_pv, app.CallingApp.t_L, app.CallingApp.p_L, app.CallingApp.R_L);
        end

        % Value changed function: cdEditField
        function cdEditFieldValueChanged(app, event)
            app.updateLD();   
        end

        % Value changed function: clEditField
        function clEditFieldValueChanged(app, event)
            app.updateLD();            
        end

        % Value changed function: DRSSwitch
        function DRSSwitchValueChanged(app, event)
            value = app.DRSSwitch.Value;
            if  value == "cd"
             app.DragCoeffc_wEditField.Enable = true;
             app.DRSefficiencyEditField.Enable = false;
            else
             app.DragCoeffc_wEditField.Enable = false;
             app.DRSefficiencyEditField.Enable = true;
            end
        end

        % Value changed function: DRSCheckBox
        function DRSCheckBoxValueChanged(app, event)
            DRSUpdateCheckBox(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AeroParametersPanel = uipanel(app.UIFigure);
            app.AeroParametersPanel.Title = 'Aero Parameters';
            app.cdEditFieldLabel = uilabel(app.AeroParametersPanel);
            app.cdEditFieldLabel.Text = 'cd [-]';
            app.cdEditField = uieditfield(app.AeroParametersPanel, 'numeric');
            app.cdEditField.ValueChangedFcn = createCallbackFcn(app, @cdEditFieldValueChanged, true);
            app.AmEditFieldLabel = uilabel(app.AeroParametersPanel);
            app.AmEditFieldLabel.Text = 'A [m²]';
            app.AmEditField = uieditfield(app.AeroParametersPanel, 'numeric');
            app.UITable = uitable(app.AeroParametersPanel);
            app.UITable.ColumnName = {'Speed [km/h]'; 'Downforce [N]'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.Button = uibutton(app.AeroParametersPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.AeroParametersPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.DownforcemultiplierEditFieldLabel = uilabel(app.AeroParametersPanel);
            app.DownforcemultiplierEditFieldLabel.Text = 'Downforce multiplier [-]';
            app.DownforcemultiplierEditField = uieditfield(app.AeroParametersPanel, 'numeric');
            app.DownforcemultiplierEditField.Value = 1;
            app.SaveButton = uibutton(app.AeroParametersPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.clEditFieldLabel = uilabel(app.AeroParametersPanel);
            app.clEditFieldLabel.Text = 'cl [-]';
            app.clEditField = uieditfield(app.AeroParametersPanel, 'numeric');
            app.clEditField.ValueChangedFcn = createCallbackFcn(app, @clEditFieldValueChanged, true);
            app.ResetButton = uibutton(app.AeroParametersPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.AeroLoadFrontLabel = uilabel(app.AeroParametersPanel);
            app.AeroLoadFrontLabel.Text = 'Aero Load Front [-]';
            app.AeroLoadFrontEditField = uieditfield(app.AeroParametersPanel, 'numeric');
            app.AeroLoadFrontEditField.Value = 0.5;
            app.PlotAeroDataButton = uibutton(app.AeroParametersPanel, 'push');
            app.PlotAeroDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAeroDataButtonPushed, true);
            app.PlotAeroDataButton.Text = 'Plot Aero Data';
            app.LDLabel = uilabel(app.AeroParametersPanel);
            app.LDLabel.Text = 'L/D';
            app.SimulatewithDRSPanel = uipanel(app.AeroParametersPanel);
            app.SimulatewithDRSPanel.Title = 'Simulate with DRS';
            app.DRSefficiencyEditField = uieditfield(app.SimulatewithDRSPanel, 'numeric');
            app.DRSefficiencyEditField.Limits = [0 1];
            app.DRSefficiencyEditField.Enable = 'off';
            app.DragCoeffc_wEditField = uieditfield(app.SimulatewithDRSPanel, 'numeric');
            app.DragCoeffc_wEditField.Limits = [0 Inf];
            app.DRSCheckBox = uicheckbox(app.SimulatewithDRSPanel);
            app.DRSCheckBox.ValueChangedFcn = createCallbackFcn(app, @DRSCheckBoxValueChanged, true);
            app.DRSCheckBox.Text = 'DRS';
            app.DRSSwitch = uiswitch(app.SimulatewithDRSPanel, 'slider');
            app.DRSSwitch.Items = {'efficiency', 'cd'};
            app.DRSSwitch.ValueChangedFcn = createCallbackFcn(app, @DRSSwitchValueChanged, true);
            app.DRSSwitch.Value = 'efficiency';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Aerodynamik(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end