classdef DataAnalysisGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        UITable2                      matlab.ui.control.Table
        GridLayout7                   matlab.ui.container.GridLayout
        ClearPlotsButton              matlab.ui.control.Button
        BrakeStatusLamp               matlab.ui.control.Lamp
        BrakeStatusLampLabel          matlab.ui.control.Label
        DRSStatusLamp                 matlab.ui.control.Lamp
        DRSStatusLampLabel            matlab.ui.control.Label
        GridLayout6                   matlab.ui.container.GridLayout
        ClearAllButton                matlab.ui.control.Button
        LoadRunButton                 matlab.ui.control.Button
        Panel_2                       matlab.ui.container.Panel
        KmhLabel                      matlab.ui.control.Label
        RPMLabel_3                    matlab.ui.control.Label
        RPMGauge_2                    matlab.ui.control.Gauge
        GearLabel                     matlab.ui.control.Label
        RPMGauge                      matlab.ui.control.Gauge
        RPMLabel_2                    matlab.ui.control.Label
        UITable_2                     matlab.ui.control.Table
        UITable                       matlab.ui.control.Table
        RightPanel                    matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        GridLayout5                   matlab.ui.container.GridLayout
        Slider_2                      matlab.ui.control.Slider
        Panel                         matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        DropDown                      matlab.ui.control.DropDown
        DropDownLabel                 matlab.ui.control.Label
        SteeringAngleLabel            matlab.ui.control.Label
        Copyright2021BalticRacingbyEricDorniedenLabel  matlab.ui.control.Label
        CreateRunDataButton           matlab.ui.control.Button
        OpenDataInspectorButton       matlab.ui.control.Button
        PlotIDSpinner                 matlab.ui.control.Spinner
        PlotIDSpinnerLabel            matlab.ui.control.Label
        PauseButton                   matlab.ui.control.Button
        PlayButton                    matlab.ui.control.Button
        HoldonCheckBox                matlab.ui.control.CheckBox
        RadiusLabel                   matlab.ui.control.Label
        PlotButton                    matlab.ui.control.Button
        VariableDropDown              matlab.ui.control.DropDown
        VariableDropDownLabel         matlab.ui.control.Label
        RunNumberSpinner              matlab.ui.control.Spinner
        RunNumberSpinnerLabel         matlab.ui.control.Label
        ScatterVariableDropDown       matlab.ui.control.DropDown
        ScatterVariableDropDownLabel  matlab.ui.control.Label
        RPMLabel                      matlab.ui.control.Label
        SpeedLabel                    matlab.ui.control.Label
        DistanceLabel                 matlab.ui.control.Label
        TimeLabel                     matlab.ui.control.Label
        Image4_2                      matlab.ui.control.Image
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        Toolbar                       matlab.ui.container.Toolbar
        PushTool                      matlab.ui.container.toolbar.PushTool
        PushTool2                     matlab.ui.container.toolbar.PushTool
        PushTool3                     matlab.ui.container.toolbar.PushTool
        PushTool4                     matlab.ui.container.toolbar.PushTool
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        steeringImage = imread('SteeringWheel1.png');
    end
    
    properties (Access = private)
        CallingApp % Mainapp Object
    end
    
    methods (Access = public)
        
        function changeColorScheme(app)
            PrimaryColor = app.CallingApp.PrimaryColor;
            OnPrimaryColor = app.CallingApp.OnPrimaryColor;   
            PrimaryVariantColor = app.CallingApp.PrimaryVariantColor;
            OnPrimaryVariantColor = app.CallingApp.OnPrimaryVariantColor;
            SecondaryColor = app.CallingApp.SecondaryColor;
            OnSecondaryColor = app.CallingApp.OnSecondaryColor;
            BackgroundColor = app.CallingApp.BackgroundColor;
            OnBackgroundColor = app.CallingApp.OnBackgroundColor;
            SurfaceColor = app.CallingApp.SurfaceColor;
            OnSurfaceColor = app.CallingApp.OnSurfaceColor;
            ErrorColor = app.CallingApp.ErrorColor;
            OnErrorColor = app.CallingApp.OnErrorColor;
            BorderType = app.CallingApp.BorderType;                 
            Host_Logo = app.CallingApp.Host_Logo;

            app.UIFigure.Color = BackgroundColor;
            app.GridLayout.BackgroundColor = BackgroundColor;
            app.GridLayout2.BackgroundColor = BackgroundColor;
            app.GridLayout3.BackgroundColor = BackgroundColor;
            app.GridLayout4.BackgroundColor = BackgroundColor;
            app.GridLayout5.BackgroundColor = BackgroundColor;
            app.GridLayout6.BackgroundColor = BackgroundColor;
            app.GridLayout7.BackgroundColor = BackgroundColor;

            %% Panels
            app.Panel.ForegroundColor = OnSurfaceColor;
            app.Panel.BackgroundColor = SurfaceColor;
            app.Panel.BorderType = BorderType;      

            app.Panel_2.ForegroundColor = OnSurfaceColor;
            app.Panel_2.BackgroundColor = SurfaceColor;
            app.Panel_2.BorderType = BorderType;

            %% Labels
            app.SpeedLabel.FontColor = OnPrimaryColor;
            app.RPMLabel.FontColor = OnPrimaryColor;
            app.TimeLabel.FontColor = OnPrimaryColor;
            app.DistanceLabel.FontColor = OnPrimaryColor;
            app.RadiusLabel.FontColor = OnPrimaryColor;
            app.SteeringAngleLabel.FontColor = OnPrimaryColor;
            app.DRSStatusLampLabel.FontColor = OnPrimaryColor;
            app.BrakeStatusLampLabel.FontColor = OnPrimaryColor;
            app.GearLabel.FontColor = OnPrimaryColor;
            app.KmhLabel.FontColor = OnPrimaryColor;
            app.RPMLabel_2.FontColor = OnPrimaryColor;
            app.RPMLabel_3.FontColor = OnPrimaryColor;

            %% Buttons
            app.PlotButton
            app.PlotButton
            
            app.PlayButton
            app.PlayButton

            app.PauseButton
            app.PauseButton

            %% 
            app.Slider_2.FontColor = OnPrimaryColor;

            app.ScatterVariableDropDownLabel.FontColor = OnPrimaryColor;
            app.ScatterVariableDropDown.BackgroundColor = PrimaryColor;
            app.ScatterVariableDropDown.FontColor = OnPrimaryColor;

            app.DropDownLabel.FontColor = OnPrimaryColor;
            app.DropDown.BackgroundColor = PrimaryColor;
            app.DropDown.FontColor = OnPrimaryColor;

            app.PlotIDSpinnerLabel.FontColor = OnPrimaryColor;
            app.PlotIDSpinner.BackgroundColor = PrimaryColor;
            app.PlotIDSpinner.FontColor = OnPrimaryColor;

            app.RunNumberSpinnerLabel.FontColor = OnPrimaryColor;
            app.RunNumberSpinner.BackgroundColor = PrimaryColor;
            app.RunNumberSpinner.FontColor = OnPrimaryColor;

            app.VariableDropDownLabel.FontColor = OnPrimaryColor;
            app.VariableDropDown.BackgroundColor = PrimaryColor;
            app.VariableDropDown.FontColor = OnPrimaryColor;

            app.RPMGauge.BackgroundColor = BackgroundColor;
            app.RPMGauge.FontColor = OnPrimaryColor;
            app.RPMGauge_2.BackgroundColor = BackgroundColor;
            app.RPMGauge_2.FontColor = OnPrimaryColor;

            %% UIAxes
            app.UIAxes_3.Title.Color = OnPrimaryColor;
            app.UIAxes_3.XColor = OnPrimaryColor;
            app.UIAxes_3.YColor = OnPrimaryColor;
            app.UIAxes_3.Color = SurfaceColor;

            app.UIAxes_2.Title.Color = OnPrimaryColor;
            app.UIAxes_2.XColor = OnPrimaryColor;
            app.UIAxes_2.YColor = OnPrimaryColor;
            app.UIAxes_2.Color = SurfaceColor;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.CallingApp = mainapp;  

            changeColorScheme(app);
        end

        % Clicked callback: PushTool3
        function PushTool3Clicked(app, event)
            app.UITable.Position = [9,244,0,0];
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            
            rotatedImage = imrotate(app.steeringImage, app.Slider_2.Value);
            app.Image4_2.ImageSource(rotatedImage);

            
            %imshow(rotatedImage,'Parent',app.UIAxes_3);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {715, 715};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {337, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Name'; 'Value'; 'Plot'; 'Color'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.GridLayout2);
            app.UITable_2.ColumnName = {'Run Name'; 'RunID'; 'Time'; 'Gap'};
            app.UITable_2.RowName = {};
            app.Panel_2 = uipanel(app.GridLayout2);
            app.RPMLabel_2 = uilabel(app.Panel_2);
            app.RPMLabel_2.Text = 'RPM';
            app.RPMGauge = uigauge(app.Panel_2, 'circular');
            app.RPMGauge.Limits = [0 200];
            app.GearLabel = uilabel(app.Panel_2);
            app.GearLabel.Text = '1';
            app.RPMGauge_2 = uigauge(app.Panel_2, 'circular');
            app.RPMGauge_2.Limits = [0 6000];
            app.RPMLabel_3 = uilabel(app.Panel_2);
            app.RPMLabel_3.Text = 'RPM';
            app.KmhLabel = uilabel(app.Panel_2);
            app.KmhLabel.Text = 'Km/h';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.LoadRunButton = uibutton(app.GridLayout6, 'push');
            app.LoadRunButton.Text = 'Load Run';
            app.ClearAllButton = uibutton(app.GridLayout6, 'push');
            app.ClearAllButton.Text = 'Clear All';
            app.GridLayout7 = uigridlayout(app.GridLayout2);
            app.DRSStatusLampLabel = uilabel(app.GridLayout7);
            app.DRSStatusLampLabel.Text = 'DRS Status';
            app.DRSStatusLamp = uilamp(app.GridLayout7);
            app.BrakeStatusLampLabel = uilabel(app.GridLayout7);
            app.BrakeStatusLampLabel.Text = 'Brake Status';
            app.BrakeStatusLamp = uilamp(app.GridLayout7);
            app.ClearPlotsButton = uibutton(app.GridLayout7, 'push');
            app.ClearPlotsButton.Text = 'Clear Plots';
            app.UITable2 = uitable(app.GridLayout2);
            app.UITable2.ColumnName = {'Name'; 'Value'};
            app.UITable2.RowName = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxes_3 = uiaxes(app.GridLayout3);
            app.UIAxes_3.XTickLabel = '';
            app.UIAxes_3.YTickLabel = '';
            app.UIAxes_2 = uiaxes(app.GridLayout3);
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTickLabel = '';
            app.Panel = uipanel(app.GridLayout3);
            app.Panel.Title = 'Panel';
            app.GridLayout4 = uigridlayout(app.Panel);
            app.Image4_2 = uiimage(app.GridLayout4);
            app.Image4_2.ImageSource = 'SteeringWheel1.png';
            app.TimeLabel = uilabel(app.GridLayout4);
            app.TimeLabel.Text = 'Time:';
            app.DistanceLabel = uilabel(app.GridLayout4);
            app.DistanceLabel.Text = 'Distance:';
            app.SpeedLabel = uilabel(app.GridLayout4);
            app.SpeedLabel.Text = 'Speed:';
            app.RPMLabel = uilabel(app.GridLayout4);
            app.RPMLabel.Text = 'RPM: ';
            app.ScatterVariableDropDownLabel = uilabel(app.GridLayout4);
            app.ScatterVariableDropDownLabel.Text = 'Scatter Variable';
            app.ScatterVariableDropDown = uidropdown(app.GridLayout4);
            app.ScatterVariableDropDown.Items = {'None', 'RPM'};
            app.ScatterVariableDropDown.Value = 'None';
            app.RunNumberSpinnerLabel = uilabel(app.GridLayout4);
            app.RunNumberSpinnerLabel.Text = 'Run Number';
            app.RunNumberSpinner = uispinner(app.GridLayout4);
            app.VariableDropDownLabel = uilabel(app.GridLayout4);
            app.VariableDropDownLabel.Text = 'Variable';
            app.VariableDropDown = uidropdown(app.GridLayout4);
            app.PlotButton = uibutton(app.GridLayout4, 'push');
            app.PlotButton.Text = 'Plot';
            app.RadiusLabel = uilabel(app.GridLayout4);
            app.RadiusLabel.Text = 'Radius:';
            app.HoldonCheckBox = uicheckbox(app.GridLayout4);
            app.HoldonCheckBox.Text = 'Hold on';
            app.PlayButton = uibutton(app.GridLayout4, 'push');
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.GridLayout4, 'push');
            app.PauseButton.Text = 'Pause';
            app.PlotIDSpinnerLabel = uilabel(app.GridLayout4);
            app.PlotIDSpinnerLabel.Text = 'PlotID';
            app.PlotIDSpinner = uispinner(app.GridLayout4);
            app.OpenDataInspectorButton = uibutton(app.GridLayout4, 'push');
            app.OpenDataInspectorButton.Text = 'Open Data Inspector';
            app.CreateRunDataButton = uibutton(app.GridLayout4, 'push');
            app.CreateRunDataButton.Text = 'Create Run Data';
            app.Copyright2021BalticRacingbyEricDorniedenLabel = uilabel(app.GridLayout4);
            app.Copyright2021BalticRacingbyEricDorniedenLabel.Text = 'Copyright © 2021 Baltic Racing by Eric Dornieden';
            app.SteeringAngleLabel = uilabel(app.GridLayout4);
            app.SteeringAngleLabel.Text = 'Steering Angle';
            app.DropDownLabel = uilabel(app.GridLayout4);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.GridLayout4);
            app.DropDown.Items = {'0.5x', '1x', '2x', '5x', '10x', '20'};
            app.DropDown.Value = '1x';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.Slider_2 = uislider(app.GridLayout5);
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.Value = 95.4321123702816;
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Load Run from result file'};
            app.PushTool.Icon = 'plus-31216_1280.png';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Clear All'};
            app.PushTool2.Icon = '1542971200.svg';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'Open Simulink Data Inspector.'};
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Icon = 'SimulinkIcon.png';
            app.PushTool3.Separator = 'on';
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.Icon = '1420410486.svg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataAnalysisGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end