classdef Environment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ResetButton            matlab.ui.control.Button
        gmsEditField           matlab.ui.control.NumericEditField
        gmsEditFieldLabel      matlab.ui.control.Label
        SaveButton             matlab.ui.control.Button
        AirPanel               matlab.ui.container.Panel
        R_LJkgKEditField       matlab.ui.control.NumericEditField
        R_LJkgKEditFieldLabel  matlab.ui.control.Label
        p_LPaEditField         matlab.ui.control.NumericEditField
        p_LPaEditField_2Label  matlab.ui.control.Label
        t_LCEditField          matlab.ui.control.NumericEditField
        t_LCEditField_2Label   matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
    end
    
    methods (Access = private)
        
        function saveData(app)
            app.CallingApp.t_L = app.t_LCEditField.Value;
            app.CallingApp.p_L = app.p_LPaEditField.Value;
            app.CallingApp.R_L = app.R_LJkgKEditField.Value;
            app.CallingApp.g = app.gmsEditField.Value;
        end
        
        function loadApp(app)
            app.t_LCEditField.Value = app.CallingApp.t_L;
            app.p_LPaEditField.Value = app.CallingApp.p_L;
            app.R_LJkgKEditField.Value = app.CallingApp.R_L;
            app.gmsEditField.Value = app.CallingApp.g;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.CallingApp = mainapp;
            
            loadApp(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            saveData(app);
            
            % Disable Environment Options button while dialog is open
            app.CallingApp.EnvironmentButton.Enable = 'on';
            
            delete(app)            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveData(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            loadApp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AirPanel = uipanel(app.UIFigure);
            app.AirPanel.Title = 'Air ';
            app.t_LCEditField_2Label = uilabel(app.AirPanel);
            app.t_LCEditField_2Label.Text = 't_L [°C]';
            app.t_LCEditField = uieditfield(app.AirPanel, 'numeric');
            app.p_LPaEditField_2Label = uilabel(app.AirPanel);
            app.p_LPaEditField_2Label.Text = 'p_L [Pa]';
            app.p_LPaEditField = uieditfield(app.AirPanel, 'numeric');
            app.p_LPaEditField.ValueDisplayFormat = '%.0f';
            app.R_LJkgKEditFieldLabel = uilabel(app.AirPanel);
            app.R_LJkgKEditFieldLabel.Text = 'R_L [J/(kg*K)]';
            app.R_LJkgKEditField = uieditfield(app.AirPanel, 'numeric');
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.gmsEditFieldLabel = uilabel(app.UIFigure);
            app.gmsEditFieldLabel.Text = 'g [m/s²]';
            app.gmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Environment(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end