classdef TelemetryConverter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SelectionDataPanel       matlab.ui.container.Panel
        DistanceLabel            matlab.ui.control.Label
        TelemetryConverterPanel  matlab.ui.container.Panel
        Panel                    matlab.ui.container.Panel
        DataPointsLabel          matlab.ui.control.Label
        UpdatePlotButton         matlab.ui.control.Button
        ConvertButton            matlab.ui.control.Button
        startEditField           matlab.ui.control.NumericEditField
        startEditFieldLabel      matlab.ui.control.Label
        finishEditField          matlab.ui.control.NumericEditField
        finishEditFieldLabel     matlab.ui.control.Label
        loadcsvButton            matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp;   % Main app object#
        csv_import;
        Data;
    end
    
    methods (Access = private)
        
        %% Draws the GPS path with the given start and end values
        function updatePlot(app)
            
            cla(app.UIAxes);
            
            start = app.startEditField.Value;
            finish = app.finishEditField.Value;
            
            plot(app.UIAxes,app.Data.GPS_Longitude(start:finish),app.Data.GPS_Latitude(start:finish),'k')
            hold(app.UIAxes, 'on'); 
            
            % Mark start point
            plot(app.UIAxes,app.Data.GPS_Longitude(start),app.Data.GPS_Latitude(start),'r*')
            % Mark end point 
            plot(app.UIAxes,app.Data.GPS_Longitude(finish),app.Data.GPS_Latitude(finish),'b*')
            
            app.DistanceLabel.Text = ['Distance: ' num2str(app.Data.Distance(finish)-app.Data.Distance(start)) ' Km'];
        end
        
        function writeCSV(app)         
            Data_section = app.Data(app.startEditField.Value:app.finishEditField.Value,:);
            
            time = Data_section(:,1);
            distance = Data_section(:,2);
            
            Data_section(:,1) = distance;
            Data_section(:,2) = time;
            
            Data_section.Time = Data_section.Time-Data_section.Time(1);
            
            writetable(Data_section,'loggedData.csv') 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.CallingApp = mainapp;
        end

        % Button pushed function: loadcsvButton
        function loadcsvButtonPushed(app, event)
            [app.csv_import, ~] = uigetfile('*.csv');  %opens a .csv file
            
            %Data = readtable(app.csv_import,'NumHeaderLines',17);

            opts = detectImportOptions(app.csv_import,'NumHeaderLines',17); % number of header lines which are to be ignored
            opts.VariableNamesLine = 13; % row number which has variable names
            opts.DataLine = 18; % row number from which the actual data starts
            opts.VariableUnitsLine = 15; % row number which has units specified
            app.Data = readtable(app.csv_import,opts);
            
            % Pre initalize 
            app.finishEditField.Value = height(app.Data);
            
            app.DataPointsLabel.Text = ['Data Points: ' num2str(height(app.Data))];
            
            app.startEditField.Limits = [1 height(app.Data)-1];
            app.finishEditField.Limits = [1 height(app.Data)];
            
            updatePlot(app);
        end

        % Button pushed function: UpdatePlotButton
        function UpdatePlotButtonPushed(app, event)
            updatePlot(app);
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
           writeCSV(app);         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TelemetryConverterPanel = uipanel(app.UIFigure);
            app.TelemetryConverterPanel.Title = 'Telemetry Converter';
            app.UIAxes = uiaxes(app.TelemetryConverterPanel);
            title(app.UIAxes, 'Track')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.TelemetryConverterPanel);
            app.Panel.Title = 'Panel';
            app.loadcsvButton = uibutton(app.Panel, 'push');
            app.loadcsvButton.ButtonPushedFcn = createCallbackFcn(app, @loadcsvButtonPushed, true);
            app.loadcsvButton.Text = 'load .csv';
            app.finishEditFieldLabel = uilabel(app.Panel);
            app.finishEditFieldLabel.Text = 'finish';
            app.finishEditField = uieditfield(app.Panel, 'numeric');
            app.finishEditField.LowerLimitInclusive = 'off';
            app.finishEditField.Limits = [1 Inf];
            app.finishEditField.RoundFractionalValues = 'on';
            app.finishEditField.ValueDisplayFormat = '%.0f';
            app.finishEditField.Value = 2;
            app.startEditFieldLabel = uilabel(app.Panel);
            app.startEditFieldLabel.Text = 'start';
            app.startEditField = uieditfield(app.Panel, 'numeric');
            app.startEditField.Limits = [1 Inf];
            app.startEditField.RoundFractionalValues = 'on';
            app.startEditField.ValueDisplayFormat = '%.0f';
            app.startEditField.Value = 1;
            app.ConvertButton = uibutton(app.Panel, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.UpdatePlotButton = uibutton(app.Panel, 'push');
            app.UpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotButtonPushed, true);
            app.UpdatePlotButton.Text = 'Update Plot';
            app.DataPointsLabel = uilabel(app.Panel);
            app.DataPointsLabel.Text = 'Data Points:';
            app.SelectionDataPanel = uipanel(app.UIFigure);
            app.SelectionDataPanel.Title = 'Selection Data';
            app.DistanceLabel = uilabel(app.SelectionDataPanel);
            app.DistanceLabel.Text = 'Distance:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TelemetryConverter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end