classdef TrackAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ScatterVariableDropDown       matlab.ui.control.DropDown
        ScatterVariableDropDownLabel  matlab.ui.control.Label
        TimemapButton                 matlab.ui.control.Button
        CornerPlotButton              matlab.ui.control.Button
        HeightProfileButton           matlab.ui.control.Button
        TrackReportPanel              matlab.ui.container.Panel
        PercentStraightsLabel         matlab.ui.control.Label
        DataPointsLabel               matlab.ui.control.Label
        TrackLengthLabel              matlab.ui.control.Label
        NumberofApexesLabel           matlab.ui.control.Label
        MaximumCornerRadiusLabel      matlab.ui.control.Label
        MinimumCornerRadiusLabel      matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        TrackAnalyzerPanel            matlab.ui.container.Panel
        ApexSizeEditField             matlab.ui.control.NumericEditField
        ApexSizeEditFieldLabel        matlab.ui.control.Label
        UpdateDataButton              matlab.ui.control.Button
        LoadResultFileButton          matlab.ui.control.Button
        UpperRadiusEditField          matlab.ui.control.NumericEditField
        UpperRadiusEditFieldLabel     matlab.ui.control.Label
        LowerRadiusEditField          matlab.ui.control.NumericEditField
        LowerRadiusEditFieldLabel     matlab.ui.control.Label
        Copyright2021BalticRacingbyEricDorniedenLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp   % Main app object#
        file_run_1;
        result;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.CallingApp = mainapp;
                       
            
        end

        % Button pushed function: LoadResultFileButton
        function LoadResultFileButtonPushed(app, event)
            [app.file_run_1, ~] = uigetfile('*.mat');  %open a mat file
        
            app.result = load(app.file_run_1, '-mat');
            
%             app.UpdateDataButtonPushed(app, event);
        end

        % Button pushed function: UpdateDataButton
        function UpdateDataButtonPushed(app, event)
            % Clears the UIAxes so it can be recreated
            cla(app.UIAxes,'reset');
            
            % Checks if the track file is 4 or 5 columns and read it acordingly          
            [~, columns] = size(app.result.Track);
            
            if (columns == 4)
                x_Track = app.result.Track(:,1);           % [m] X-Koordinate der Strecke
                y_Track = app.result.Track(:,2);           % [m] Y-Koordinate der Strecke
                z_Track = [];                              % [m] Z-Koordinate der Strecke
                s = app.result.Track(:,3);                 % [m] Verlauf der Streckenlänge
                R_Track = app.result.Track(:,4);                 % [m] Kurvenradien
            else
                x_Track = app.result.Track(:,1);           % [m] X-Koordinate der Strecke
                y_Track = app.result.Track(:,2);           % [m] Y-Koordinate der Strecke
                z_Track = app.result.Track(:,3);           % [m] Z-Koordinate der Strecke
                s = app.result.Track(:,4);                 % [m] Verlauf der Streckenlänge
                R_Track = app.result.Track(:,5);                 % [m] Kurvenradien
            end   
            
            apex = (zeros(1,length(app.result.ApexIndizes)));
            
            % Creates an array of every apex on the track
            k = 1;
            for i = 1:length(R_Track)
                if ismember(i,app.result.ApexIndizes)
                    apex(k,1) = app.result.Track(i,1);
                    apex(k,2) = app.result.Track(i,2);
                    isApex(i) = 1;
                    k = k + 1;
                else
                    isApex(i) = 0;
                end
            end
            
            % Scatters the track with Radius
            switch app.ScatterVariableDropDown.Value
                case '1' % Raadius
                    scatter(app.UIAxes, app.result.Track(1:end,1),app.result.Track(1:end,2),40,R_Track,'filled')
                case '2' % Time/Point
                    for i = 1:size(app.result.Track,1)-1
                        s_corr(i) = app.result.Track(i+1,4)-app.result.Track(i,4);
                        timeP(i) = s_corr(i)./app.result.vV(i);
                    end
                    
                    scatter(app.UIAxes, app.result.Track(1:end-1,1),app.result.Track(1:end-1,2),40,timeP,'filled')
                case '3' % Elevation
                    scatter(app.UIAxes, app.result.Track(1:end,1),app.result.Track(1:end,2),40,z_Track,'filled')
            end

            
%             annotation('textbox', [0.6, 0.8, 0.1, 0.1],...
%                 'String', {['minimum R = ' num2str(min(R_Track)) ' m'],...
%                 ['maximum R = ' num2str(max(R_Track)) ' m'],...             
%                 ['num of apexes = ' num2str(length(apex))]})  
            
            title(app.UIAxes,'Track Analyzer','FontSize',12)           
            xlabel(app.UIAxes,'X-Coordinate [m]','FontSize',10)       
            ylabel(app.UIAxes,'Y-Coordinate [m]','FontSize',10)
            colormap(app.UIAxes,jet)
            caxis(app.UIAxes, [app.LowerRadiusEditField.Value app.UpperRadiusEditField.Value]) % Sets the minimum and maximum value for the colorbar 
            c = colorbar(app.UIAxes);   
            c.Label.String = 'Corner Radius';
            
            % Hold plot to scatter the apexes of the track
            hold(app.UIAxes);
            
            try % Try catch is needed because of tracks without apexes (Accel)
                scatter(app.UIAxes,apex(1:end,1),apex(1:end,2),app.ApexSizeEditField.Value) % Draws points for every apex on the track
            catch
            end
            
            %% Calculate percent straights 
            straights = 0;
            for i = 1:size(x_Track,1)
                if (R_Track(i) == inf)
                    straights = straights + 1;
                end
            end
            straights_percent = straights / i;
            
            
            %% Generate and write Track Report data
            app.MinimumCornerRadiusLabel.Text = ['Minimum Corner Radius: ' num2str(min(R_Track)) ' m'];
            app.MaximumCornerRadiusLabel.Text = ['Maximum Corner Radius: ' num2str(max(R_Track)) ' m'];
            app.NumberofApexesLabel.Text = ['Number of Apexes: ' num2str(length(apex))];
            app.TrackLengthLabel.Text = ['Track Length: ' num2str(s(end)) ' m'];
            app.DataPointsLabel.Text = ['Data Points: ' num2str(size(app.result.Track,1))];
            app.PercentStraightsLabel.Text = ['Percent Straights: ' num2str(straights_percent*100) ' %']
            
            %% Creaate Track Table
            app.UITable.Data = [x_Track y_Track z_Track R_Track s isApex.' app.result.vV];
        end

        % Button pushed function: HeightProfileButton
        function HeightProfileButtonPushed(app, event)
            if(size(app.result.Track,2) == 5)
                plot(app.result.Track(:,4), app.result.Track(:,3));
                xlabel('Track Length [m]');
                ylabel('Elevation [m]');
                title('Track Height Profile Plot')
            else
                errordlg('Track has no height profile!');
            end
        end

        % Button pushed function: CornerPlotButton
        function CornerPlotButtonPushed(app, event)
            if(size(app.result.Track,2) == 5)
                plot(app.result.Track(:,4), app.result.Track(:,5));
                xlabel('Track Length [m]');
                ylabel('Corner Radius [m]');
                ylim([app.LowerRadiusEditField.Value app.UpperRadiusEditField.Value]);
            else
                plot(app.result.Track(:,3), app.result.Track(:,4));
                xlabel('Track Length [m]');
                ylabel('Corner Radius [m]');
                ylim([app.LowerRadiusEditField.Value app.UpperRadiusEditField.Value]);
            end
        end

        % Button pushed function: TimemapButton
        function TimemapButtonPushed(app, event)
            if(size(app.result.Track,2) == 5)
                
                for i = 1:size(app.result.Track,1)-1
                    s_corr(i) = app.result.Track(i+1,4)-app.result.Track(i,4);
                end
                
                plot(app.result.Track(:,4), s_corr./app.result.vV(1:end));
                title('Time per Data Point');
                xlabel('Track Length [m]');
                ylabel('Distance/Velocity [1/sec]');
                
                yyaxis('right')
                hold on
                plot(app.result.Track(:,4), app.result.Track(:,5));
                ylabel('Corner Radius [m]');
                ylim([app.LowerRadiusEditField.Value app.UpperRadiusEditField.Value]);
            else
                for i = 1:size(app.result.Track,1)-1
                    s_corr = app.result.Track(i+1,3)-app.result.Track(i,3);
                end
                
                plot(app.result.Track(:,3), s_corr./app.result.vV(1:end));
                title('Time per Data Point');
                xlabel('Track Length [m]');
                ylabel('Distance/Velocity [1/sec]');
                
                yyaxis('right')
                hold on
                plot(app.result.Track(:,3), app.result.Track(:,4));
                ylabel('Corner Radius [m]');
                ylim([app.LowerRadiusEditField.Value app.UpperRadiusEditField.Value]);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Track Analyzer')
            xlabel(app.UIAxes, 'x-Coordinate')
            ylabel(app.UIAxes, 'y-Coordinate')
            zlabel(app.UIAxes, 'Z')
            app.Copyright2021BalticRacingbyEricDorniedenLabel = uilabel(app.UIFigure);
            app.Copyright2021BalticRacingbyEricDorniedenLabel.Text = 'Copyright © 2021 Baltic Racing by Eric Dornieden';
            app.TrackAnalyzerPanel = uipanel(app.UIFigure);
            app.TrackAnalyzerPanel.Title = 'Track Analyzer';
            app.LowerRadiusEditFieldLabel = uilabel(app.TrackAnalyzerPanel);
            app.LowerRadiusEditFieldLabel.Text = 'Lower Radius';
            app.LowerRadiusEditField = uieditfield(app.TrackAnalyzerPanel, 'numeric');
            app.LowerRadiusEditField.UpperLimitInclusive = 'off';
            app.LowerRadiusEditField.Limits = [0 Inf];
            app.UpperRadiusEditFieldLabel = uilabel(app.TrackAnalyzerPanel);
            app.UpperRadiusEditFieldLabel.Text = 'Upper Radius';
            app.UpperRadiusEditField = uieditfield(app.TrackAnalyzerPanel, 'numeric');
            app.UpperRadiusEditField.UpperLimitInclusive = 'off';
            app.UpperRadiusEditField.Limits = [0 Inf];
            app.UpperRadiusEditField.Value = 100;
            app.LoadResultFileButton = uibutton(app.TrackAnalyzerPanel, 'push');
            app.LoadResultFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadResultFileButtonPushed, true);
            app.LoadResultFileButton.Text = 'Load Result File';
            app.UpdateDataButton = uibutton(app.TrackAnalyzerPanel, 'push');
            app.UpdateDataButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDataButtonPushed, true);
            app.UpdateDataButton.Text = 'Update Data';
            app.ApexSizeEditFieldLabel = uilabel(app.TrackAnalyzerPanel);
            app.ApexSizeEditFieldLabel.Text = 'ApexSize';
            app.ApexSizeEditField = uieditfield(app.TrackAnalyzerPanel, 'numeric');
            app.ApexSizeEditField.Value = 120;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'x-Coordinate'; 'y-Coordinate'; 'z-Coordinate'; 'Radius'; 'Distance'; 'isApex'; 'Velocity'};
            app.UITable.RowName = {};
            app.TrackReportPanel = uipanel(app.UIFigure);
            app.TrackReportPanel.Title = 'Track Report';
            app.MinimumCornerRadiusLabel = uilabel(app.TrackReportPanel);
            app.MinimumCornerRadiusLabel.Text = 'Minimum Corner Radius: ';
            app.MaximumCornerRadiusLabel = uilabel(app.TrackReportPanel);
            app.MaximumCornerRadiusLabel.Text = 'Maximum Corner Radius: ';
            app.NumberofApexesLabel = uilabel(app.TrackReportPanel);
            app.NumberofApexesLabel.Text = 'Number of Apexes:';
            app.TrackLengthLabel = uilabel(app.TrackReportPanel);
            app.TrackLengthLabel.Text = 'Track Length:';
            app.DataPointsLabel = uilabel(app.TrackReportPanel);
            app.DataPointsLabel.Text = 'Data Points:';
            app.PercentStraightsLabel = uilabel(app.TrackReportPanel);
            app.PercentStraightsLabel.Text = 'Percent Straights:';
            app.HeightProfileButton = uibutton(app.UIFigure, 'push');
            app.HeightProfileButton.ButtonPushedFcn = createCallbackFcn(app, @HeightProfileButtonPushed, true);
            app.HeightProfileButton.Text = 'Height Profile';
            app.CornerPlotButton = uibutton(app.UIFigure, 'push');
            app.CornerPlotButton.ButtonPushedFcn = createCallbackFcn(app, @CornerPlotButtonPushed, true);
            app.CornerPlotButton.Text = 'Corner Plot';
            app.TimemapButton = uibutton(app.UIFigure, 'push');
            app.TimemapButton.ButtonPushedFcn = createCallbackFcn(app, @TimemapButtonPushed, true);
            app.TimemapButton.Text = 'Time map';
            app.ScatterVariableDropDownLabel = uilabel(app.UIFigure);
            app.ScatterVariableDropDownLabel.Text = 'Scatter Variable';
            app.ScatterVariableDropDown = uidropdown(app.UIFigure);
            app.ScatterVariableDropDown.Items = {'Radius', 'Time/Point', 'Elevation'};
            app.ScatterVariableDropDown.ItemsData = {'1', '2', '3'};
            app.ScatterVariableDropDown.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrackAnalyzer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end